classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HMIUIFigure                   matlab.ui.Figure
        LaunchButton                  matlab.ui.control.Button
        SimulationrunningLamp         matlab.ui.control.Lamp
        TextArea                      matlab.ui.control.TextArea
        Gauge                         matlab.ui.control.LinearGauge
        CloseButton                   matlab.ui.control.Button
        Attack_startLabel             matlab.ui.control.Label
        Attack_startSlider            matlab.ui.control.Slider
        Attack_endSliderLabel         matlab.ui.control.Label
        Attack_endSlider              matlab.ui.control.Slider
        Tank1leakrateSliderLabel      matlab.ui.control.Label
        Tank1leakrateSlider           matlab.ui.control.Slider
        valueinmm3Label               matlab.ui.control.Label
        Tank1overflowrateSliderLabel  matlab.ui.control.Label
        Tank1overflowrateSlider       matlab.ui.control.Slider
        Qf1_leak                      matlab.ui.control.NumericEditField
        Qf2_leak                      matlab.ui.control.NumericEditField
        Qfo1_overflow                 matlab.ui.control.NumericEditField
        Tank2leakrateSliderLabel      matlab.ui.control.Label
        Tank2leakrateSlider           matlab.ui.control.Slider
    end

    properties (Access = public)
    end
    
    properties (Access = private)
        Qf1 = 1e-4;
        Qf2 = 1e-4;
        Qof1 = 1e-4;
        Qf1_max = 1e-3;
        Qf2_max = 1e-3;
        Qfo1_max = 1e-3;
        h0 = 0;
        SimuTime = 250;
    end

    methods (Access = public)
    
        function UnlockButtons(app)
            app.LaunchButton.Enable = 'on';
            app.ParametersButton.Enable = 'on';
        end
        function UpdateValues(app,Qf1_2,Qf2_2,Qof1_2,h0_2,SimuTime_2)
            app.Qf1=Qf1_2;
            app.Qf2=Qf2_2;
            app.Qof1=Qof1_2;
            app.h0=h0_2;
            app.SimuTime=SimuTime_2;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Gauge.Visible = 'off';
            app.TextArea.Visible = 'off';
            app.Gauge.Limits = [0 app.SimuTime];
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            app.TextArea.Value = 'Simulation running';
            app.TextArea.Visible = 'on';
            app.SimulationrunningLamp.Color = 'red';
            %set_param('CHEM_FULL_BENCHMARK', 'SimulationCommand', 'start')
            sim('CHEM_FULL_BENCHMARK');
            app.Gauge.Limits = [0 app.SimuTime];
            app.Gauge.Visible = 'on';
            app.Gauge.Visible = 'off';
            app.Gauge.Value = 0;
            app.TextArea.Visible = 'off';
            app.SimulationrunningLamp.Color = 'green';
            app.LaunchButton.Enable = 'on';            
        end

        % Callback function
        function ParametersButtonPushed(app, event)
            
        end

        % Close request function: HMIUIFigure
        function HMIUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete;
        end

        % Callback function
        function FaultsButtonPushed(app, event)
            
        end

        % Callback function
        function AttacksButtonPushed(app, event)
            
        end

        % Callback function
        function updateFaultScenario(app, event)
            
        end

        % Value changing function: Tank1leakrateSlider
        function tan1_leak_rate_change(app, event)
            changingValue = event.Value;
            app.Qf1_leak.Value = changingValue * app.Qf1_max/100 * 1000;
        end

        % Value changing function: Tank2leakrateSlider
        function tank2_leak_rate_change(app, event)
            changingValue = event.Value;
            app.Qf2_leak.Value = changingValue * app.Qf2_max/100 * 1000;
            
        end

        % Value changing function: Tank1overflowrateSlider
        function tank1_overflow_rate_change(app, event)
            changingValue = event.Value;
            app.Qfo1_overflow.Value = changingValue * app.Qfo1_max/100 * 1000;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HMIUIFigure = uifigure('Visible', 'off');
            app.HMIUIFigure.Name = 'HMI';
            app.HMIUIFigure.CloseRequestFcn = createCallbackFcn(app, @HMIUIFigureCloseRequest, true);
            app.LaunchButton = uibutton(app.HMIUIFigure, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
            app.SimulationrunningLamp = uilamp(app.HMIUIFigure);
            app.TextArea = uitextarea(app.HMIUIFigure);
            app.TextArea.Editable = 'off';
            app.Gauge = uigauge(app.HMIUIFigure, 'linear');
            app.Gauge.Limits = [0 250];
            app.CloseButton = uibutton(app.HMIUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Attack_startLabel = uilabel(app.HMIUIFigure);
            app.Attack_startLabel.Text = 'Attack_start';
            app.Attack_startSlider = uislider(app.HMIUIFigure);
            app.Attack_startSlider.Limits = [0 250];
            app.Attack_endSliderLabel = uilabel(app.HMIUIFigure);
            app.Attack_endSliderLabel.Text = 'Attack_end';
            app.Attack_endSlider = uislider(app.HMIUIFigure);
            app.Attack_endSlider.Limits = [0 250];
            app.Tank1leakrateSliderLabel = uilabel(app.HMIUIFigure);
            app.Tank1leakrateSliderLabel.Text = 'Tank 1 leak rate ';
            app.Tank1leakrateSlider = uislider(app.HMIUIFigure);
            app.Tank1leakrateSlider.ValueChangingFcn = createCallbackFcn(app, @tan1_leak_rate_change, true);
            app.valueinmm3Label = uilabel(app.HMIUIFigure);
            app.valueinmm3Label.Text = 'value in mm^3';
            app.Tank1overflowrateSliderLabel = uilabel(app.HMIUIFigure);
            app.Tank1overflowrateSliderLabel.Text = 'Tank 1 overflow rate';
            app.Tank1overflowrateSlider = uislider(app.HMIUIFigure);
            app.Tank1overflowrateSlider.ValueChangingFcn = createCallbackFcn(app, @tank1_overflow_rate_change, true);
            app.Qf1_leak = uieditfield(app.HMIUIFigure, 'numeric');
            app.Qf1_leak.Editable = 'off';
            app.Qf2_leak = uieditfield(app.HMIUIFigure, 'numeric');
            app.Qf2_leak.Editable = 'off';
            app.Qfo1_overflow = uieditfield(app.HMIUIFigure, 'numeric');
            app.Qfo1_overflow.Editable = 'off';
            app.Tank2leakrateSliderLabel = uilabel(app.HMIUIFigure);
            app.Tank2leakrateSliderLabel.Text = 'Tank 2 leak rate';
            app.Tank2leakrateSlider = uislider(app.HMIUIFigure);
            app.Tank2leakrateSlider.ValueChangingFcn = createCallbackFcn(app, @tank2_leak_rate_change, true);
            % Show the figure after all components are created
            app.HMIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HMIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HMIUIFigure)
        end
    end
end